classdef updated_app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout          matlab.ui.container.GridLayout
        YOUWINYAYYYYYLabel  matlab.ui.control.Label
        YOULOSELabel        matlab.ui.control.Label
        WORDLELabel         matlab.ui.control.Label
        row_5_column_1      matlab.ui.control.Label
        row_4_column_1      matlab.ui.control.Label
        row_3_column_1      matlab.ui.control.Label
        row_2_column_1      matlab.ui.control.Label
        row_1_column_1      matlab.ui.control.Label
        edit_field          matlab.ui.control.EditField
        submitButton        matlab.ui.control.Button
        row_5_column_4      matlab.ui.control.Label
        row_4_column_4      matlab.ui.control.Label
        row_3_column_4      matlab.ui.control.Label
        row_2_column_4      matlab.ui.control.Label
        row_1_column_4      matlab.ui.control.Label
        row_5_column_3      matlab.ui.control.Label
        row_4_column_3      matlab.ui.control.Label
        row_3_column_3      matlab.ui.control.Label
        row_2_column_3      matlab.ui.control.Label
        row_1_column_3      matlab.ui.control.Label
        row_5_column_2      matlab.ui.control.Label
        row_4_column_2      matlab.ui.control.Label
        row_3_column_2      matlab.ui.control.Label
        row_2_column_2      matlab.ui.control.Label
        row_1_column_2      matlab.ui.control.Label
        guess_the_word      matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: submitButton
        function submitButtonPushed(comp, event)
   
    
    persistent pressCount 
    if isempty(pressCount)
        pressCount = 0;
    end
    
    pressCount = pressCount + 1;
    
    % Create a matrix to hold the values
    valueMatrix = comp.edit_field.Value(1, :); % Assuming edit_field.Value is a 1x4 array
    
    % Use a matrix to reference the row and column dynamically
    row = pressCount;
    
    if row <= 5  % Ensure the row doesn't exceed the 5th row
        for col = 1:4  % Loop through columns 1 to 4
            comp.(['row_' num2str(row) '_column_' num2str(col)]).Text = valueMatrix(col);
        end
    end
%     persistent pressCount 
%     if isempty(pressCount)
%         pressCount = 0;
%     end
%   pressCount = pressCount + 1;
%         switch pressCount
%             case 1           
% comp.row_1_column_1.Text = comp.edit_field.Value(1,1);
%  comp.row_1_column_2.Text = comp.edit_field.Value(1,2);
% comp.row_1_column_3.Text = comp.edit_field.Value(1,3);
% comp.row_1_column_4.Text = comp.edit_field.Value(1,4);
% 
%             case 2
% comp.row_2_column_1.Text = comp.edit_field.Value(1,1);
% comp.row_2_column_2.Text = comp.edit_field.Value(1,2);
% comp.row_2_column_3.Text = comp.edit_field.Value(1,3);
% comp.row_2_column_4.Text = comp.edit_field.Value(1,4); 
%             case 3             
% comp.row_3_column_1.Text = comp.edit_field.Value(1,1);
% comp.row_3_column_2.Text = comp.edit_field.Value(1,2);
% comp.row_3_column_3.Text = comp.edit_field.Value(1,3);
% comp.row_3_column_4.Text = comp.edit_field.Value(1,4); 
%             case 4         
% comp.row_4_column_1.Text = comp.edit_field.Value(1,1);
% comp.row_4_column_2.Text = comp.edit_field.Value(1,2);
% comp.row_4_column_3.Text = comp.edit_field.Value(1,3);
% comp.row_4_column_4.Text = comp.edit_field.Value(1,4); 
%             case 5    
% comp.row_5_column_1.Text = comp.edit_field.Value(1,1);
% comp.row_5_column_2.Text = comp.edit_field.Value(1,2);
% comp.row_5_column_3.Text = comp.edit_field.Value(1,3);
% comp.row_5_column_4.Text = comp.edit_field.Value(1,4); 


        %%% if input word = guessed word turn on you win 
        %%% if input word is not guessed word, you loose 
            
            % read from text area, make vector 
            %%% make sure in right row
            %%% for loop
            %%%need variable which keeps tract of row 
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 624 475];
            comp.GridLayout = uigridlayout(comp);
            comp.guess_the_word = uilabel(comp.GridLayout);
            comp.guess_the_word.Text = {'Guess the word and check your answer by pressing the submit button.'; 'lowercase letters only!'};
            comp.row_1_column_2 = uilabel(comp.GridLayout);
            comp.row_1_column_2.Text = '';
            comp.row_2_column_2 = uilabel(comp.GridLayout);
            comp.row_2_column_2.Text = '';
            comp.row_3_column_2 = uilabel(comp.GridLayout);
            comp.row_3_column_2.Text = '';
            comp.row_4_column_2 = uilabel(comp.GridLayout);
            comp.row_4_column_2.Text = '';
            comp.row_5_column_2 = uilabel(comp.GridLayout);
            comp.row_5_column_2.Text = '';
            comp.row_1_column_3 = uilabel(comp.GridLayout);
            comp.row_1_column_3.Text = '';
            comp.row_2_column_3 = uilabel(comp.GridLayout);
            comp.row_2_column_3.Text = '';
            comp.row_3_column_3 = uilabel(comp.GridLayout);
            comp.row_3_column_3.Text = '';
            comp.row_4_column_3 = uilabel(comp.GridLayout);
            comp.row_4_column_3.Text = '';
            comp.row_5_column_3 = uilabel(comp.GridLayout);
            comp.row_5_column_3.Text = '';
            comp.row_1_column_4 = uilabel(comp.GridLayout);
            comp.row_1_column_4.Text = '';
            comp.row_2_column_4 = uilabel(comp.GridLayout);
            comp.row_2_column_4.Text = '';
            comp.row_3_column_4 = uilabel(comp.GridLayout);
            comp.row_3_column_4.Text = '';
            comp.row_4_column_4 = uilabel(comp.GridLayout);
            comp.row_4_column_4.Text = '';
            comp.row_5_column_4 = uilabel(comp.GridLayout);
            comp.row_5_column_4.Text = '';
            comp.submitButton = uibutton(comp.GridLayout, 'push');
            comp.submitButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @submitButtonPushed, true);
            comp.submitButton.Text = 'submit';
            comp.edit_field = uieditfield(comp.GridLayout, 'text');
            comp.edit_field.CharacterLimits = [0 4];
            comp.edit_field.InputType = 'letters';
            comp.row_1_column_1 = uilabel(comp.GridLayout);
            comp.row_1_column_1.Text = '';
            comp.row_2_column_1 = uilabel(comp.GridLayout);
            comp.row_2_column_1.Text = '';
            comp.row_3_column_1 = uilabel(comp.GridLayout);
            comp.row_3_column_1.Text = '';
            comp.row_4_column_1 = uilabel(comp.GridLayout);
            comp.row_4_column_1.Text = '';
            comp.row_5_column_1 = uilabel(comp.GridLayout);
            comp.row_5_column_1.Text = '';
            comp.WORDLELabel = uilabel(comp.GridLayout);
            comp.WORDLELabel.Text = 'WORDLE';
            comp.YOULOSELabel = uilabel(comp.GridLayout);
            comp.YOULOSELabel.Visible = 'off';
            comp.YOULOSELabel.Text = 'YOU LOSE!';
            comp.YOUWINYAYYYYYLabel = uilabel(comp.GridLayout);
            comp.YOUWINYAYYYYYLabel.Visible = 'off';
            comp.YOUWINYAYYYYYLabel.Text = 'YOU WIN! YAYYYYY';
        end
    end
end